classdef guimain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIMain          matlab.ui.Figure
        MenuFile         matlab.ui.container.Menu
        QuitMenu         matlab.ui.container.Menu
        EditMenu         matlab.ui.container.Menu
        PreferencesMenu  matlab.ui.container.Menu
        TilesfolderMenu  matlab.ui.container.Menu
        MapOld           matlab.ui.control.UIAxes
        Map              matlab.ui.control.Image
        DetailsPanel     matlab.ui.container.Panel
        DebugLabel       matlab.ui.control.Label
    end

    properties (Access = public)        
        % Map
        mapInteractiveElements      = struct();
        mapFolder
        mapCurrentZoom
        mapMinZoom
        mapMaxZoom
        mapCenterCoordinates
        mapSize
        mapTiles
        
        % Callbacks
        cbMouseWheelCount           = 0
        mapClicked                  = false
        mapMouseSource              = 'normal'
        mapClickedCoords            = [0 0]
        mapMotionCounter            = [0 0]
        mapFigureIntersectionPoint  = [0 0]
    end
    
    properties (Access = private)
        leonarAppDataFolder
        leonarOptions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setting up AppData folder
            app.leonarAppDataFolder = getapplicationdatadir('LeONAR', true, false);
            
            % Reading options or creating options file if not found
            try
                app.leonarOptions = ReadXmlOptionsFile(app.leonarAppDataFolder);
            catch ME
                if strcmp(ME.identifier,'LeONAR:XMLOptionsFileNotFound')
                    % Default options structure
                    app.leonarOptions = GetDefaultOptionsStructure();
                    
                    % Write options file
                    WriteXmlOptionsFile(app.leonarAppDataFolder,app.leonarOptions);
                else
                    rethrow(ME);
                end
            end
            
            % Update options
            try
                app.mapFolder       = app.leonarOptions.userMapFolder;
                app.mapCurrentZoom  = str2double(app.leonarOptions.userZoom);
            catch
                % Options file is not coherent with version
                % Default options structure
                app.leonarOptions = GetDefaultOptionsStructure();
                
                % Write options file
                WriteXmlOptionsFile(app.leonarAppDataFolder,app.leonarOptions);
                
                % Update options
                % TODO : function ?
                app.mapFolder       = app.leonarOptions.userMapFolder;
                app.mapCurrentZoom  = str2double(app.leonarOptions.userZoom);
            end
            
            %
            try
                [app.mapMinZoom,app.mapMaxZoom] = GetMinMaxZoomFromTilesFolder(app.mapFolder);
                
                % Updating map size
                app.mapSize = DetermineMapSize(app.Map.Position(3),app.Map.Position(4));
                
                % Initializing
                app.mapInteractiveElements.Ship = Ship(47.54101,-2.90171); % CROUESTY, sortie port, TODO: use GNSS
                
                % Initializing map, centering on current user position
                app.mapCenterCoordinates = app.mapInteractiveElements.Ship.Coordinates;
                app.mapTiles = UpdateMapDisplay(app,app.mapInteractiveElements.Ship);
            catch ME
                if strcmp(ME.identifier,'LeONAR:InvalidTilesFolder')
                    errordlg('Tiles folder path undefined. Go to ''Edit > Preferences > Tiles folder'' and select a valid tiles folder.');
                else
                    rethrow(ME);
                end
            end
            
        end

        % Window button down function: GUIMain
        function GUIMainWindowButtonDownFcn(app, event)
            [source,coords_source,mouse_source] = leonar_WindowButtonDownFcn(app,event);
            switch source
                case 'Map'
                    app.mapClicked                  = true;
                    app.mapMouseSource              = mouse_source;
                    app.mapClickedCoords            = coords_source;
                    app.DebugLabel.Text             = num2str(app.mapClickedCoords);
                    
                    switch mouse_source
                        case 'normal'
                            % TODO: get to pixel in VisibleMap (which is not necessarily equal to mapClickedCoords)
                            % TODO: implement InteractiveElements detection
                            % TODO: not working very well yet ...
                            [~,displayedHeight,displayedWidth] = app.mapTiles.GetPixelsToDisplay(app.Map);
                            app.mapTiles.InteractiveMap(displayedHeight(app.mapClickedCoords(2)),displayedWidth(app.mapClickedCoords(1)))
                    end
                otherwise
                    app.mapClicked = false;
            end
        end

        % Window scroll wheel function: GUIMain
        function GUIMainWindowScrollWheel(app, event)
            leonar_WindowScrollWheel(app,event);
        end

        % Window button up function: GUIMain
        function GUIMainWindowButtonUp(app, event)
            app.mapClicked          = false;
            app.mapMotionCounter    = [0 0]; % reset motion counter
        end

        % Size changed function: GUIMain
        function GUIMainSizeChanged(app, event)
            position = app.GUIMain.Position;
            % TODO : update mapTiled projection
            % TODO : resize children
        end

        % Window button motion function: GUIMain
        function GUIMainWindowButtonMotion(app, event)
            leonar_WindowButtonMotion(app,event);
        end

        % Callback function: GUIMain, QuitMenu
        function GUIMainCloseRequest(app, event)
            % Save zoom level
            app.leonarOptions.userZoom = num2str(app.mapCurrentZoom);
            
            % Write options file
            WriteXmlOptionsFile(app.leonarAppDataFolder,app.leonarOptions);
            
            % Close app
            delete(app);
        end

        % Menu selected function: TilesfolderMenu
        function TilesFolderMenuSelected(app, event)
            % Save current map folder
            saveMapFolder = app.mapFolder;
            
            % Ask user to define new map tiles folder
            tmpMapFolder = uigetdir(app.mapFolder);

            % Check if a folder was selected
            if tmpMapFolder ~= 0
                app.mapFolder = tmpMapFolder;
                
                % Try
                try
                    [app.mapMinZoom,app.mapMaxZoom] = GetMinMaxZoomFromTilesFolder(app.mapFolder);
                    app.mapCurrentZoom = max(app.mapMinZoom,min(app.mapCurrentZoom,app.mapMaxZoom));
                    
                    % Updating map size
                    app.mapSize = DetermineMapSize(app.Map.Position(3),app.Map.Position(4));
                    
                    % Initializing map
                    app.mapTiles = UpdateMapDisplay(app,app.mapInteractiveElements.Ship);
                    
                    % Update XML file
                    app.leonarOptions.userMapFolder = app.mapFolder;
                    app.leonarOptions.userZoom = app.mapCurrentZoom;
                    WriteXmlOptionsFile(app.leonarAppDataFolder,app.leonarOptions);
                catch ME
                    if strcmp(ME.identifier,'LeONAR:InvalidTilesFolder')
                        % Display information
                        errordlg('Selected folder is not a compatible tile folder. Changes discarded.');
                        
                        % Do nothing (new folder is not a map folder)
                        app.mapFolder = saveMapFolder;
                    else
                        rethrow(ME);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIMain = uifigure('Visible', 'off');
            app.GUIMain.Name = 'LeONAR';
            app.GUIMain.CloseRequestFcn = createCallbackFcn(app, @GUIMainCloseRequest, true);
            app.GUIMain.SizeChangedFcn = createCallbackFcn(app, @GUIMainSizeChanged, true);
            app.GUIMain.WindowButtonDownFcn = createCallbackFcn(app, @GUIMainWindowButtonDownFcn, true);
            app.GUIMain.WindowButtonUpFcn = createCallbackFcn(app, @GUIMainWindowButtonUp, true);
            app.GUIMain.WindowButtonMotionFcn = createCallbackFcn(app, @GUIMainWindowButtonMotion, true);
            app.GUIMain.WindowScrollWheelFcn = createCallbackFcn(app, @GUIMainWindowScrollWheel, true);
            app.MenuFile = uimenu(app.GUIMain);
            app.MenuFile.Text = 'File';
            app.QuitMenu = uimenu(app.MenuFile);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIMainCloseRequest, true);
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.GUIMain);
            app.EditMenu.Text = 'Edit';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.TilesfolderMenu = uimenu(app.PreferencesMenu);
            app.TilesfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @TilesFolderMenuSelected, true);
            app.TilesfolderMenu.Text = 'Tiles folder';
            app.MapOld = uiaxes(app.GUIMain);
            title(app.MapOld, '')
            xlabel(app.MapOld, '')
            ylabel(app.MapOld, '')
            app.MapOld.XAxisLocation = 'origin';
            app.MapOld.XTick = [];
            app.MapOld.XTickLabel = '';
            app.MapOld.YAxisLocation = 'origin';
            app.MapOld.YTick = [];
            app.Map = uiimage(app.GUIMain);
            app.DetailsPanel = uipanel(app.GUIMain);
            app.DetailsPanel.Title = 'Details';
            app.DebugLabel = uilabel(app.DetailsPanel);
            app.DebugLabel.Text = 'Debug';
            % Show the figure after all components are created
            app.GUIMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guimain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIMain)
        end
    end
end